classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Semnal_de_intrare               matlab.ui.control.UIAxes
        GeneraresemnalButton            matlab.ui.control.Button
        DatedeintrareEditFieldLabel     matlab.ui.control.Label
        DatedeintrareEditField          matlab.ui.control.EditField
        CodareButton                    matlab.ui.control.Button
        SemnalCodat                     matlab.ui.control.UIAxes
        DecodareButton                  matlab.ui.control.Button
        BitiDecodatiEditFieldLabel      matlab.ui.control.Label
        BitiDecodatiEditField           matlab.ui.control.EditField
        GenerarebitirandomButton        matlab.ui.control.Button
        BitigeneratiEditFieldLabel      matlab.ui.control.Label
        BitigeneratiEditField           matlab.ui.control.EditField
        LungimeSecventaEditFieldLabel   matlab.ui.control.Label
        LungimeSecventaEditField        matlab.ui.control.NumericEditField
        CodareBitiGeneratiButton        matlab.ui.control.Button
        DecodareBitiGeneratiButton      matlab.ui.control.Button
        BitiDecodatiEditField_2Label    matlab.ui.control.Label
        BitiDecodatiEditField_2         matlab.ui.control.EditField
        CodaresidecodareB8ZSLabel       matlab.ui.control.Label
        IntroducetisecventadebitiLabel  matlab.ui.control.Label
        GeneratirandomsecventadebitiLabel  matlab.ui.control.Label
        ValoareMedieEditFieldLabel      matlab.ui.control.Label
        ValoareMedieEditField           matlab.ui.control.EditField
    end


    methods (Access = private)
        
        function codare(app,bits)
            bitrate = 1;
n = 20;
T = length(bits)/bitrate;
%Fs=1000;
N = n*length(bits);
dt = T/N;
t = 0:dt:T;
L=length(t);
x = zeros(1,length(t));
counter = 0;
lastbit = -1;
for i=1:length(bits)
  if bits(i)==0
    counter = counter + 1;
    if counter==8
      x((i-1-7)*n+1:(i-7)*n) = 0;
      x((i-1-6)*n+1:(i-6)*n) = 0;
      x((i-1-5)*n+1:(i-5)*n) = 0;
      x((i-1-4)*n+1:(i-4)*n) = lastbit;
      x((i-1-3)*n+1:(i-3)*n) = -lastbit;
      lastbit = -lastbit;
      x((i-1-2)*n+1:(i-2)*n) = 0;
      x((i-1-1)*n+1:(i-1)*n) = lastbit;
      x((i-1)*n+1:i*n) = -lastbit;
      lastbit = -lastbit;
      counter = 0;
    end
  else
    counter = 0;
    x((i-1)*n+1:i*n) = -lastbit;
    lastbit = -lastbit;
  end
  plot(app.SemnalCodat, t, x, 'Linewidth', 3);
%FFT
a=2^nextpow2(L);
y=fft(x,a);
f = n*(0:(a/2))/a;
P = abs(y/a);
plot(f,P(1:a/2+1)) 
title('FFT');
xlabel('Frequency (f)')
%mean
valMedie=mean(x);

valMedie2=valMedie*1000;
valString=num2str(valMedie2);
strConcat=strcat(valString,'*10E-3');
valString2=char(strConcat);
app.ValoareMedieEditField.Value=valString2;
end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DatedeintrareEditField
        function DatedeintrareEditFieldValueChanged(app, event)
          
            
        end

        % Button pushed function: GeneraresemnalButton
        function GeneraresemnalButtonPushed(app, event)
              value = app.DatedeintrareEditField.Value;
                bits=str2num(value);
                x = [0:length(bits)];
                bits = [bits, 0 ];
                stairs(app.Semnal_de_intrare,x,bits,'LineWidth',3);
        end

        % Button pushed function: CodareButton
        function CodareButtonPushed(app, event)
value = app.DatedeintrareEditField.Value;
bits=str2num(value);
codare(app,bits);

        end

        % Button pushed function: DecodareButton
        function DecodareButtonPushed(app, event)
value = app.DatedeintrareEditField.Value;
bits=str2num(value);  
bitrate = 1;
n = 1000;
T = length(bits)/bitrate;
N = n*length(bits);
dt = T/N;
t = 0:dt:T;
coded = zeros(1,length(t));
counter = 0;
lastbit = 1;
for i=1:length(bits)
  if bits(i)==0
    counter = counter + 1;
    if counter==8
      coded((i-1-7)*n+1:(i-7)*n) = 0;
      coded((i-1-6)*n+1:(i-6)*n) = 0;
      coded((i-1-5)*n+1:(i-5)*n) = 0;
      coded((i-1-4)*n+1:(i-4)*n) = lastbit;
      coded((i-1-3)*n+1:(i-3)*n) = -lastbit;
      lastbit = -lastbit;
      coded((i-1-2)*n+1:(i-2)*n) = 0;
      coded((i-1-1)*n+1:(i-1)*n) = lastbit;
      coded((i-1)*n+1:i*n) = -lastbit;
      lastbit = -lastbit;
      counter = 0;
    end
  else
    counter = 0;
    coded((i-1)*n+1:i*n) = -lastbit;
    lastbit = -lastbit;
  end
end
counter = 0;
lastbit = 1;
for i = 1:length(t)
  if t(i)>counter
    counter = counter + 1;
    if coded(i)==lastbit
      result(counter:counter+4) = 0;
      counter = counter + 4;
    else
      if(coded(i)==0)
        result(counter) = 0;
      else
        result(counter) = 1;
        lastbit = -lastbit;
      end
    end
  end
end
str=num2str(result);
app.BitiDecodatiEditField.Value=str;
        end

        % Button pushed function: GenerarebitirandomButton
        function GenerarebitirandomButtonPushed(app, event)
            n=app.LungimeSecventaEditField.Value;
            bits = randi([0 1], 1,n);
            bitsStr=num2str(bits);
            app.BitigeneratiEditField.Value=bitsStr;
            x = [0:length(bits)];
                bits = [bits, 0 ];
                 stairs(app.Semnal_de_intrare,x,bits,'LineWidth',3);
        end

        % Button pushed function: CodareBitiGeneratiButton
        function CodareBitiGeneratiButtonPushed(app, event)
            value = app.BitigeneratiEditField.Value;
bits=str2num(value);
codare(app,bits);
        end

        % Button pushed function: DecodareBitiGeneratiButton
        function DecodareBitiGeneratiButtonPushed(app, event)
value = app.BitigeneratiEditField.Value;
bits=str2num(value);     
bitrate = 1;
n = 1000;
T = length(bits)/bitrate;
N = n*length(bits);
dt = T/N;
t = 0:dt:T;
coded = zeros(1,length(t));
counter = 0;
lastbit = 1;
for i=1:length(bits)
  if bits(i)==0
    counter = counter + 1;
    if counter==8
      coded((i-1-7)*n+1:(i-7)*n) = 0;
      coded((i-1-6)*n+1:(i-6)*n) = 0;
      coded((i-1-5)*n+1:(i-5)*n) = 0;
      coded((i-1-4)*n+1:(i-4)*n) = lastbit;
      coded((i-1-3)*n+1:(i-3)*n) = -lastbit;
      lastbit = -lastbit;
      coded((i-1-2)*n+1:(i-2)*n) = 0;
      coded((i-1-1)*n+1:(i-1)*n) = lastbit;
      coded((i-1)*n+1:i*n) = -lastbit;
      lastbit = -lastbit;
      counter = 0;
    end
  else
    counter = 0;
    coded((i-1)*n+1:i*n) = -lastbit;
    lastbit = -lastbit;
  end
end
counter = 0;
lastbit = 1;
for i = 1:length(t)
  if t(i)>counter
    counter = counter + 1;
    if coded(i)==lastbit
      result(counter:counter+4) = 0;
      counter = counter + 4;
    else
      if(coded(i)==0)
        result(counter) = 0;
      else
        result(counter) = 1;
        lastbit = -lastbit;
      end
    end
  end
end
str=num2str(result);
app.BitiDecodatiEditField_2.Value=str;
        end

        % Value changed function: BitiDecodatiEditField
        function BitiDecodatiEditFieldValueChanged(app, event)
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Semnal_de_intrare = uiaxes(app.UIFigure);
            title(app.Semnal_de_intrare, 'Semnal Original')
            xlabel(app.Semnal_de_intrare, 't ')
            ylabel(app.Semnal_de_intrare, 'A')
            app.GeneraresemnalButton = uibutton(app.UIFigure, 'push');
            app.GeneraresemnalButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraresemnalButtonPushed, true);
            app.GeneraresemnalButton.Text = 'Generare semnal';
            app.DatedeintrareEditFieldLabel = uilabel(app.UIFigure);
            app.DatedeintrareEditFieldLabel.Text = 'Date de intrare';
            app.DatedeintrareEditField = uieditfield(app.UIFigure, 'text');
            app.DatedeintrareEditField.ValueChangedFcn = createCallbackFcn(app, @DatedeintrareEditFieldValueChanged, true);
            app.DatedeintrareEditField.Value = '1 0 1 1 1 1 0 0 1 1 0 1 ';
            app.CodareButton = uibutton(app.UIFigure, 'push');
            app.CodareButton.ButtonPushedFcn = createCallbackFcn(app, @CodareButtonPushed, true);
            app.CodareButton.Text = 'Codare';
            app.SemnalCodat = uiaxes(app.UIFigure);
            title(app.SemnalCodat, 'Semnal Codat')
            xlabel(app.SemnalCodat, 't')
            ylabel(app.SemnalCodat, 'A')
            app.DecodareButton = uibutton(app.UIFigure, 'push');
            app.DecodareButton.ButtonPushedFcn = createCallbackFcn(app, @DecodareButtonPushed, true);
            app.DecodareButton.Text = 'Decodare';
            app.BitiDecodatiEditFieldLabel = uilabel(app.UIFigure);
            app.BitiDecodatiEditFieldLabel.Text = 'BitiDecodati';
            app.BitiDecodatiEditField = uieditfield(app.UIFigure, 'text');
            app.BitiDecodatiEditField.ValueChangedFcn = createCallbackFcn(app, @BitiDecodatiEditFieldValueChanged, true);
            app.GenerarebitirandomButton = uibutton(app.UIFigure, 'push');
            app.GenerarebitirandomButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarebitirandomButtonPushed, true);
            app.GenerarebitirandomButton.Text = 'Generare biti random';
            app.BitigeneratiEditFieldLabel = uilabel(app.UIFigure);
            app.BitigeneratiEditFieldLabel.Text = 'Biti generati';
            app.BitigeneratiEditField = uieditfield(app.UIFigure, 'text');
            app.LungimeSecventaEditFieldLabel = uilabel(app.UIFigure);
            app.LungimeSecventaEditFieldLabel.Text = 'Lungime Secventa';
            app.LungimeSecventaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LungimeSecventaEditField.Value = 12;
            app.CodareBitiGeneratiButton = uibutton(app.UIFigure, 'push');
            app.CodareBitiGeneratiButton.ButtonPushedFcn = createCallbackFcn(app, @CodareBitiGeneratiButtonPushed, true);
            app.CodareBitiGeneratiButton.Text = 'Codare Biti Generati';
            app.DecodareBitiGeneratiButton = uibutton(app.UIFigure, 'push');
            app.DecodareBitiGeneratiButton.ButtonPushedFcn = createCallbackFcn(app, @DecodareBitiGeneratiButtonPushed, true);
            app.DecodareBitiGeneratiButton.Text = 'Decodare Biti Generati';
            app.BitiDecodatiEditField_2Label = uilabel(app.UIFigure);
            app.BitiDecodatiEditField_2Label.Text = 'Biti Decodati';
            app.BitiDecodatiEditField_2 = uieditfield(app.UIFigure, 'text');
            app.CodaresidecodareB8ZSLabel = uilabel(app.UIFigure);
            app.CodaresidecodareB8ZSLabel.Text = 'Codare si decodare B8ZS';
            app.IntroducetisecventadebitiLabel = uilabel(app.UIFigure);
            app.IntroducetisecventadebitiLabel.Text = 'Introduceti secventa de biti:';
            app.GeneratirandomsecventadebitiLabel = uilabel(app.UIFigure);
            app.GeneratirandomsecventadebitiLabel.Text = 'Generati random secventa de biti:';
            app.ValoareMedieEditFieldLabel = uilabel(app.UIFigure);
            app.ValoareMedieEditFieldLabel.Text = 'Valoare Medie';
            app.ValoareMedieEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end